classdef DTMF < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure   matlab.ui.Figure
        Image      matlab.ui.control.Image
        Label_2    matlab.ui.control.Label
        Image2     matlab.ui.control.Image
        Image2_2   matlab.ui.control.Image
        Image2_3   matlab.ui.control.Image
        Image2_4   matlab.ui.control.Image
        Image2_5   matlab.ui.control.Image
        Image2_6   matlab.ui.control.Image
        Image2_7   matlab.ui.control.Image
        Image2_8   matlab.ui.control.Image
        Image2_9   matlab.ui.control.Image
        Image2_10  matlab.ui.control.Image
        Image2_11  matlab.ui.control.Image
        Image2_12  matlab.ui.control.Image
        Image2_13  matlab.ui.control.Image
        Image2_14  matlab.ui.control.Image
        Image15    matlab.ui.control.Image
        Label_3    matlab.ui.control.Label
        Label_4    matlab.ui.control.Label
        Switch     matlab.ui.control.Switch
        Label_5    matlab.ui.control.Label
        Slider     matlab.ui.control.Slider
        Label_6    matlab.ui.control.Label
        Switch_2   matlab.ui.control.Switch
        UIAxes     matlab.ui.control.UIAxes
        UIAxes_2   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        fieldText; % Description
        axes1;
        axes2;
        audiofile % Description
    end
    
    
    methods (Access = private)
        
        function [signal,time] = MyGenerate(app,text)%用~？
            Fs=8000;            %采样频率为8kHz， 即采样周期为1/8000s。
            t=(1:400)/Fs;      %信号持续时间为50ms。采样400个点。
            pit=2*pi*t;         %算子
            
            %根据不同的按键，确定不同的高、低频率。
            if(strcmp(text,'1'))
                fl=697;
                fh=1209;
            elseif (strcmp(text,'2'))
                fl=697;
                fh=1336;
            elseif (strcmp(text,'3'))
                fl=697;
                fh=1477;
            elseif (strcmp(text,'4'))
                fl=770;
                fh=1209;
            elseif (strcmp(text,'5'))
                fl=770;
                fh=1336;
            elseif (strcmp(text,'6'))
                fl=770;
                fh=1477;
            elseif (strcmp(text,'7'))
                fl=852;
                fh=1209;
            elseif (strcmp(text,'8'))
                fl=852;
                fh=1336;
            elseif (strcmp(text,'9'))
                fl=852;
                fh=1477;
            elseif (strcmp(text,'0'))
                fl=941;
                fh=1336;
            elseif (strcmp(text,'*'))
                fl=941;
                fh=1209;
            elseif (strcmp(text,'#'))
                fl=941;
                fh=1477;
            elseif (strcmp(text,'A'))
                fl=697;
                fh=1633;
            elseif (strcmp(text,'B'))
                fl=770;
                fh=1633;
            elseif (strcmp(text,'C'))
                fl=852;
                fh=1633;
            elseif (strcmp(text,'D'))
                fl=941;
                fh=1633;
            elseif(strcmp(text,'gap'))
                fl=0;
                fh=0;
            end
            
            %生成波形
            y=sin(fl*pit);
            x=sin(fh*pit);
            signal=x+y;
            time=t;
            %产生白噪声
            if (app.Switch.Value == "On")
                [signal,~] = noisegen(app,signal,app.Slider.Value);
            end
           
        end
        %按钮和键盘事件统一 产生一个数字的信号
        function Callback(app,text)
            app.Label_3.Text = '';
            app.Label_2.Text = strcat(app.Label_2.Text,text);%必须要用strcat把字符变为字符串
            %生成信号序列
            [a,b]= MyGenerate(app,strcat('',text));
            %是否显示退格键
            if (isempty(text))
                app.Image15.Visible = 'off';
            else
                app.Image15.Visible = 'on';
            end
            %显示前0.05s的波形
            plot(app.axes1,b,a);
            axis(app.axes1,[0,0.05,-2,2]);
             %滤波
            if (app.Switch_2.Value == "On")
%                 signal = wdenoise(signal,'Wavelet','sym8','DenoisingMethod','SURE','ThresholdRule','Soft');
                a = Denoise(app,a);
            end
            
            %fft变换
            y=fft(a,512); %只有400个点，添0做512点的FFT。
            mag=abs(y);
            f=[1:512]*8000/512;
            
            %显示频域信号
            axes(app.axes2);
            stem(app.axes2,f,mag);
            axis(app.axes2,[500,2000,0,200]);
            
            
            %发声
            p=audioplayer(a,8000);
            play(p);
            pause(0.5);
            
        end
        
        function Myprogress(app)
            fig = app.UIFigure;
            d = uiprogressdlg(fig,'Title','Please Wait',...
                'Message','正在打开文件');
            pause(.2)
            for i = 1:100
                % Perform calculations
                % ...
                if (i > 33 && i <= 67)
                    d.Message = '正在加载信号';
                elseif(i > 67 && i < 99)
                    d.Message = '处理中···';
                elseif(i >= 99)
                    d.Message = '音频文件已生成';
                    pause(0.5)
                end
                d.Value = i/100;
                pause(0.02)
            end
            
            % Close dialog box
            close(d);
        end
        
        function [Y,NOISE] = noisegen(~,X,SNR)
            % noisegen add white Gaussian noise to a signal.
            % [Y, NOISE] = NOISEGEN(X,SNR) adds white Gaussian NOISE to X.  The SNR is in dB.
            NOISE=randn(size(X));
            NOISE=NOISE-mean(NOISE);
            signal_power = 1/length(X)*sum(X.*X);
            noise_variance = signal_power / ( 10^(SNR/10) );
            NOISE=sqrt(noise_variance)/std(NOISE)*NOISE;
            Y=X+NOISE;
        end
        
        function y = Denoise(app,signal)
            %fft变换
            x=fft(signal,512);
            mag=abs(x);
            mag(mag < 100) = 0;
            y = ifft(mag,512);
            
        end
    end
        

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Label_2.Text = "";
            app.axes1 = app.UIAxes;
            app.axes2 = app.UIAxes_2;
            app.audiofile = 'Dial.wav';
        end

        % Callback function
        function CButtonPushed(app, event)
            app.Label_2.Text = '';
            cla(app.UIAxes);
            cla(app.UIAxes_2);
            app.Image15.Visible = 'off'; %隐藏退格键
        end

        % Image clicked function: Image2, Image2_10, Image2_11, 
        % Image2_12, Image2_2, Image2_3, Image2_4, Image2_5, 
        % Image2_6, Image2_7, Image2_8, Image2_9
        function pushbutton_Callback(app, event)
            text = event.Source.Tag;%用source可以知道谁触发了回调函数
            Callback(app,text)
        end

        % Image clicked function: Image2_13
        function GenerateStreamButtonPushed(app, event)
            %获取拨号序列
            list=app.Label_2.Text;
            app.Label_2.Text = '';
            %每个信号持续0.05s，就是400个点
            %每个信号后跟随一段空信号，也是0.05s，400个点。
            %所以整个数据长度为400*2*电话号码长度。
            stream=zeros(1,length(list)*400*2);     %预建立空序列。
            
            for i=1:length(list)                 %对于每个拨号号码，做以下操作
                [a,~]=MyGenerate(app,list(i));         %生成离散信号
                for j=1:400                       %先把一个拨号的序列存进去
                    stream(j+(i-1)*2*400)=a(j);
                end
                app.Label_2.Text = strcat(app.Label_2.Text,list(i));
                sound(a,8000);
                pause(0.15); %节奏感
                [a,~]=MyGenerate(app,'gap');
                for j=1:400                       %再把间隔的空信号序列存进去
                    stream(j+(i-1)*2*400+400)=a(j);
                end
                
            end
            
            t=(1:length(list)*400*2)/8000;        %信号的横坐标——时间
            Myprogress(app);
            %将时域信号图打印在axes1上
            axes(app.axes1);
            plot(app.axes1,t,stream);
            axis(app.axes1,'auto')
            %生成fft
            y=fft(stream,length(list)*400*2);
            mag=abs(y);
            f=[1:length(list)*400*2]*8000/(length(list)*400*2);
            %进度条 为了动态效果故意变慢
            
            %将频域信号打印在axes2上
            axes(app.axes2);
            plot(app.axes2,f,mag);
            axis(app.axes2,'auto')
            try
                axis(app.axes2,[500, 1800,0,ceil(max(mag)/200)*200]); %只显示特定的频段
                %将声音保存为wav文件
                audiowrite(app.audiofile,stream,8000);
            catch
                app.Label_3.Text = '没有数字信号！';
            end
            
            
            %             sound(stream,8000);
            
        end

        % Image clicked function: Image2_14
        function DecodeButtonPushed(app, event)
            %读取音频文件
            [FileName,~] = uigetfile('*.wav','Select the audio-file');
            app.audiofile = FileName;
            [y,fs]=audioread(app.audiofile);
            app.Label_2.Text = '';
            app.Label_3.Text = strcat('当前音频文件:',app.audiofile);
            %音频的时间序列
            t=[1:length(y)]/fs;
            
            %打印时域信号
            plot(app.UIAxes,t,y);
            axis(app.UIAxes,[0,length(y)/fs,-1.5,1.5]);
            
            %fft变换
            a=fft(y,length(y));
            mag=abs(a);
            f=[1:length(y)]*8000/(length(y));
            
            %打印频域信号
            plot(app.UIAxes_2,f,mag);
            axis(app.UIAxes_2,[500,1800,0,ceil(max(mag)/200)*200]);
            
            
            %#####以下对音频进行解码#####
            
            n=length(y)/800;
            r=zeros(4*n,64);
            c=zeros(54*n,64);
            for s=1:4*n
                R=y((200*(s-1)+1):(200*s));
                
                g=fft(R,256);
                c(s,:)=abs(g(1:64));
                r(s,:)=c(s,:);
                z=find(c(s,:)<50); %************************************阀值定位50
                
                c(s,z)=zeros(size(z));
            end
            sm=[31 44;23 40;23 44;23 48;26 40;26 44;26 48;28 40;28 44;28 48;23 53;26 53;28 53;31 53;31 40;31 48];
            for i3=1:4*n
                b=nnz(c(i3,:));
                if b==2
                    q1=find(c(i3,:));
                    for i4=1:16
                        if q1==sm(i4,:)
                            AN(i3)=i4-1;
                            break;
                        end
                    end
                else
                    AN(i3)=NaN;
                end
            end
            str = '';
            %生成的序列中，每四位号码中，前两位为相同的号码，后两位为空号码。因此只要四取一就行。
            for i7=1:length(AN)/4
                switch(AN((i7-1)*4+1))
                    case 1
                        str(i7)='1';
                    case 2
                        str(i7)='2';
                    case 3
                        str(i7)='3';
                    case 4
                        str(i7)='4';
                    case 5
                        str(i7)='5';
                    case 6
                        str(i7)='6';
                    case 7
                        str(i7)='7';
                    case 8
                        str(i7)='8';
                    case 9
                        str(i7)='9';
                    case 0
                        str(i7)='0';
                    case 10
                        str(i7)='A';
                    case 11
                        str(i7)='B';
                    case 12
                        str(i7)='C';
                    case 13
                        str(i7)='D';
                    case 14
                        str(i7)='*';
                    case 15
                        str(i7)='#';
                    otherwise
                        str(i7)='X';
                end
            end
            for i=1:length(str)
                [a,~]=MyGenerate(app,str(i));
                app.Label_2.Text = strcat(app.Label_2.Text,str(i));
                sound(a,8000); %发声
                pause(0.15); %节奏感
            end
            if (isempty(app.Label_2.Text))
                app.Image15.Visible = 'off';
            else
                app.Image15.Visible = 'on';
            end
            %显示提示被解码的号码
            %             app.Label_2.Text = 'The number is:';
            %             app.Label_2.Visible = 'on';
            %             app.Label_2.Text = str;


        end

        % Image clicked function: Image15
        function Image15Clicked(app, event)
            text = app.Label_2.Text;
            text2 = '';
            if (length(text) > 1)
                for i = 1:length(text)-1
                    text2 = strcat(text2,text(i));
                end%有待优化
            else
                text2 = '';
            end
            app.Label_2.Text = text2;
            [y,Fs] = audioread("click.wav");
            sound(y,Fs);
            %是否显示退格键
            if (isempty(text2))
                app.Image15.Visible = 'off';
            else
                app.Image15.Visible = 'on';
            end
        end

        % Window key press function: UIFigure
        function UIFigureWindowKeyPress(app, event)
            key = event.Key;
            switch key
                case '1'
                    Callback(app,key);
                case '2'
                    Callback(app,key);
                case '3'
                    Callback(app,key);
                case '4'
                    Callback(app,key);
                case '5'
                    Callback(app,key);
                case '6'
                    Callback(app,key);
                case '7'
                    Callback(app,key);
                case '8'
                    Callback(app,key);
                case '9'
                    Callback(app,key);
                case '10'
                    Callback(app,key);
                case '#'
                    Callback(app,key);
                case '*'
                    Callback(app,key);
                case 'backspace'
                    Image15Clicked(app,@Image15Clicked);%@的用处？ 传递谁调用了该函数的信息
                case 'o'
                    GenerateStreamButtonPushed(app,@GenerateStreamButtonPushed);
                case 'i'
                    DecodeButtonPushed(app,@DecodeButtonPushed);
                case 'c'
                    CButtonPushed(app,@CButtonPushed);
                otherwise
                    disp(key)
            end
        end

        % Value changed function: Switch, Switch_2
        function SwitchValueChanged(app, event)
            [y,fs] = audioread("switch.wav");
            sound(y,fs);
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            [y,fs] = audioread("slider.wav");
            sound(y,fs);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @UIFigureWindowKeyPress, true);
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'interface.png';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageClickedFcn = createCallbackFcn(app, @pushbutton_Callback, true);
            app.Image2.Tag = '1';
            app.Image2.ImageSource = '1.png';
            app.Image2_2 = uiimage(app.UIFigure);
            app.Image2_2.ImageClickedFcn = createCallbackFcn(app, @pushbutton_Callback, true);
            app.Image2_2.Tag = '2';
            app.Image2_2.ImageSource = '1.png';
            app.Image2_3 = uiimage(app.UIFigure);
            app.Image2_3.ImageClickedFcn = createCallbackFcn(app, @pushbutton_Callback, true);
            app.Image2_3.Tag = '3';
            app.Image2_3.ImageSource = '1.png';
            app.Image2_4 = uiimage(app.UIFigure);
            app.Image2_4.ImageClickedFcn = createCallbackFcn(app, @pushbutton_Callback, true);
            app.Image2_4.Tag = '4';
            app.Image2_4.ImageSource = '1.png';
            app.Image2_5 = uiimage(app.UIFigure);
            app.Image2_5.ImageClickedFcn = createCallbackFcn(app, @pushbutton_Callback, true);
            app.Image2_5.Tag = '5';
            app.Image2_5.ImageSource = '1.png';
            app.Image2_6 = uiimage(app.UIFigure);
            app.Image2_6.ImageClickedFcn = createCallbackFcn(app, @pushbutton_Callback, true);
            app.Image2_6.Tag = '6';
            app.Image2_6.ImageSource = '1.png';
            app.Image2_7 = uiimage(app.UIFigure);
            app.Image2_7.ImageClickedFcn = createCallbackFcn(app, @pushbutton_Callback, true);
            app.Image2_7.Tag = '7';
            app.Image2_7.ImageSource = '1.png';
            app.Image2_8 = uiimage(app.UIFigure);
            app.Image2_8.ImageClickedFcn = createCallbackFcn(app, @pushbutton_Callback, true);
            app.Image2_8.Tag = '8';
            app.Image2_8.ImageSource = '1.png';
            app.Image2_9 = uiimage(app.UIFigure);
            app.Image2_9.ImageClickedFcn = createCallbackFcn(app, @pushbutton_Callback, true);
            app.Image2_9.Tag = '9';
            app.Image2_9.ImageSource = '1.png';
            app.Image2_10 = uiimage(app.UIFigure);
            app.Image2_10.ImageClickedFcn = createCallbackFcn(app, @pushbutton_Callback, true);
            app.Image2_10.Tag = '*';
            app.Image2_10.ImageSource = '1.png';
            app.Image2_11 = uiimage(app.UIFigure);
            app.Image2_11.ImageClickedFcn = createCallbackFcn(app, @pushbutton_Callback, true);
            app.Image2_11.Tag = '0';
            app.Image2_11.ImageSource = '1.png';
            app.Image2_12 = uiimage(app.UIFigure);
            app.Image2_12.ImageClickedFcn = createCallbackFcn(app, @pushbutton_Callback, true);
            app.Image2_12.Tag = '#';
            app.Image2_12.ImageSource = '1.png';
            app.Image2_13 = uiimage(app.UIFigure);
            app.Image2_13.ImageClickedFcn = createCallbackFcn(app, @GenerateStreamButtonPushed, true);
            app.Image2_13.Tag = '1';
            app.Image2_13.ImageSource = '1.png';
            app.Image2_14 = uiimage(app.UIFigure);
            app.Image2_14.ImageClickedFcn = createCallbackFcn(app, @DecodeButtonPushed, true);
            app.Image2_14.Tag = '1';
            app.Image2_14.ImageSource = '1.png';
            app.Image15 = uiimage(app.UIFigure);
            app.Image15.ImageClickedFcn = createCallbackFcn(app, @Image15Clicked, true);
            app.Image15.Visible = 'off';
            app.Image15.ImageSource = '退格.png';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '产生白噪音';
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '信噪比';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [-20 20];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.Value = 10;
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '滤波';
            app.Switch_2 = uiswitch(app.UIFigure, 'slider');
            app.Switch_2.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '时域曲线')
            xlabel(app.UIAxes, '单位：s')
            ylabel(app.UIAxes, '幅度')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, '频域曲线')
            xlabel(app.UIAxes_2, '单位：Hz')
            ylabel(app.UIAxes_2, '幅度')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DTMF

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end